classdef Kelompok_1_Image_Reconstruction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        reconstruction_panel      matlab.ui.container.Panel
        UIAxes_4                  matlab.ui.control.UIAxes
        edited_phantom_panel      matlab.ui.container.Panel
        UIAxes_2                  matlab.ui.control.UIAxes
        control_panel             matlab.ui.container.Panel
        ARTButton                 matlab.ui.control.Button
        VirginiaValensieneEMariscaAuliaKizzaNaufalGiselaBeaAthaliaLabel  matlab.ui.control.Label
        SSIMEditField             matlab.ui.control.NumericEditField
        SSIMEditFieldLabel        matlab.ui.control.Label
        PSNREditField             matlab.ui.control.NumericEditField
        PSNREditFieldLabel        matlab.ui.control.Label
        ProjekAkhirTeknologiPencitraanMedisKelompok1Label  matlab.ui.control.Label
        BackProjectionButton      matlab.ui.control.Button
        ChooseFilterButtonGroup   matlab.ui.container.ButtonGroup
        CosineButton              matlab.ui.control.RadioButton
        HanningButton             matlab.ui.control.RadioButton
        dot2Button                matlab.ui.control.RadioButton
        HammingButton             matlab.ui.control.RadioButton
        RamLakButton              matlab.ui.control.RadioButton
        noneButton                matlab.ui.control.RadioButton
        OriginalSinogramButton    matlab.ui.control.Button
        SinogramButton            matlab.ui.control.Button
        BlurLevelSlider           matlab.ui.control.Slider
        BlurLevelSliderLabel      matlab.ui.control.Label
        ShowEditedPhantomButton   matlab.ui.control.Button
        NoisyLevelSlider          matlab.ui.control.Slider
        NoisyLevelSliderLabel     matlab.ui.control.Label
        ShowPhantomButton         matlab.ui.control.Button
        ChoosePhantomButtonGroup  matlab.ui.container.ButtonGroup
        Phantom4Button            matlab.ui.control.RadioButton
        dotButton                 matlab.ui.control.RadioButton
        Phantom3Button            matlab.ui.control.RadioButton
        Phantom2Button            matlab.ui.control.RadioButton
        Phantom1Button            matlab.ui.control.RadioButton
        PhantomImageReconstructionLabel  matlab.ui.control.Label
        phantom_panel             matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
        sinogram_panel            matlab.ui.container.Panel
        UIAxes_3                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        circ = [  0   0 110  2; 
                -65   0  20  1; 
                  0   0  35  0; 
                 65 -25  25  4;
                 50  50  7   8];
        originalImage;
        editedImage;
        sinogram;
        reconstructedImage;
        reconstructedImageART;
    end
    
    methods (Access = private)
        function x  = ART(~,A,AT,b,x,lambda,niter,bpos)
        
        if (nargin < 7)
            bpos	= true;
        end
        
        if (nargin < 6)
            niter       = 1e2;
        end
        
        ATA	= AT(A(ones(size(x), 'single')));
        
        for i = 1:niter
            
            x  	= x + lambda*AT(b - A(x))./ATA;
            
            if (bpos)
                x(x < 0) = 0;
            end
        
            figure(1); colormap gray;
            imagesc(x);
            axis image off;
            title(num2str([i, niter], '%d / %d'));
            drawnow();
        end
        
        x   = gather(x);
        
        end

     % ART reconstruction function
    function ARTReconstruction(app, numIterations)
        % Extract relevant parameters
        nx = 170;
        theta = 0:1:179; % Projection angles
        sgr = app.sinogram;

        % Initialize the reconstructed image
        app.reconstructedImageART = zeros(nx, nx);

        % ART algorithm using the provided function
        lambda = 0.1; % Relaxation parameter

        % Create a progress bar
        h = waitbar(0, 'ART Reconstruction in Progress...', 'Name', 'Please Wait');

        for iter = 1:numIterations
            % Update progress bar
            waitbar(iter / numIterations, h, sprintf('Iteration %d / %d', iter, numIterations));

            % Perform ART reconstruction
            app.reconstructedImageART = app.ART(@(x) radon(x, theta), @(x) iradon(x, theta, 'linear', 'none', 1, nx), sgr, app.reconstructedImageART, lambda, 1, true);
        end

        % Close the progress bar
        close(h);
        
        app.reconstructedImageART = imrotate(app.reconstructedImageART,-90);
        % Display the final reconstructed image with ART in UIAxes_4
        imagesc(app.UIAxes_4, app.reconstructedImageART');
        colormap(app.UIAxes_4, 'gray');
        axis(app.UIAxes_4, 'square');
        app.UIAxes_4.DataAspectRatio = [1 1 1];
        title(app.UIAxes_4, 'Reconstructed Image using ART');
    end      

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.dotButton.Value = true;
            app.Phantom1Button.Value = false;
            app.Phantom2Button.Value = false;
            app.Phantom3Button.Value = false;
            app.dot2Button.Value = true;
            app.noneButton.Value = false;
            app.RamLakButton.Value = false;
            app.HammingButton.Value = false;
        end

        % Selection changed function: ChoosePhantomButtonGroup
        function ChoosePhantomButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChoosePhantomButtonGroup.SelectedObject;
            
            if selectedButton== app.dotButton
            elseif selectedButton == app.Phantom1Button
                    app.circ = [  0   0 110  2; 
                                -65   0  20  1; 
                                  0   0  35  0; 
                                 65  25  25  4;
                                 50 -50  7   8];                
            elseif selectedButton == app.Phantom2Button
                    app.circ = [  0   0 110  12; 
                                -47   0  40  11; 
                                 47   0  40  11; 
                                  0  20  20  13;
                                  0 -10  7   30];  
            elseif selectedButton == app.Phantom3Button
                    app.circ = [  0   0 110  20; 
                                  0   0 100  11; 
                                 10   0   7   5; 
                                -10   0   7   5;
                                  0   0   7   8];
            elseif selectedButton == app.Phantom4Button
                    app.circ = [  0   0 150  1; 
                             % 25mm
                              0  -80 20  2; 
                             % 2mm
                             50  -70  2  2; 
                             57  -63  2  2;
                             64  -56  2  2;
                             71  -49  2  2;
                             % 3mm
                             85  -21  3  2;
                             85   -7  3  2;
                             85    7  3  2;
                             85   21  3  2;
                             % 4mm
                             70   50  4  2;
                             56   60  4  2;
                             42   70  4  2;
                             28   80  4  2;
                             % 5mm
                            -20   85  5  2;
                            -40   70  5  2;
                            -60   55  5  2;
                            -80   40  5  2;
                             % 4mm
                            -90    0  6  2;
                            -75  -25  6  2;
                            -60  -50  6  2;
                            -45  -75  6  2;];                
            end

        end

        % Button pushed function: ShowPhantomButton
        function ShowPhantomButtonPushed(app, event)
        % Image parameters: number of pixels, size, etc.
        nx = 170; ny = 170;
        dx = 2;	% 2 mm / pixel
        x = dx * ((1:nx)'-(nx+1)/2);
        y = -dx * ((1:ny)'-(ny+1)/2);
        xx = x(:,ones(1,nx));
        yy = y(:,ones(1,ny))';
        %
        %	Generate data for disk phantom
        %
          phantom = zeros(nx,ny);
          for ii=1:size(app.circ,1)
            cx = app.circ(ii,1); cy = app.circ(ii,2); rad = app.circ(ii,3); amp = app.circ(ii,4);
            t = find( ((xx-cx)/rad).^2 + ((yy-cy)/rad).^2 <= 1 );
            phantom(t) = amp * ones(size(t));
          end
        %


        % Display the phantom in UIAxes
        imagesc(x, y, phantom, 'Parent', app.UIAxes); % Use imagesc instead of imshow
        colormap(app.UIAxes, 'gray');
        axis(app.UIAxes, 'square');
        app.UIAxes.DataAspectRatio = [1 1 1];

        app.originalImage = phantom;
        end

        % Button pushed function: ShowEditedPhantomButton
        function ShowEditedPhantomButtonPushed(app, event)
        % Image parameters: number of pixels, size, etc.
        nx = 170; ny = 170;
        dx = 2;	% 2 mm / pixel
        x = dx * ((1:nx)'-(nx+1)/2);
        y = -dx * ((1:ny)'-(ny+1)/2);
        xx = x(:,ones(1,nx));
        yy = y(:,ones(1,ny))';
        
        % Apply noise and blur to the phantom and display the edited phantom
            noisyLevel = app.NoisyLevelSlider.Value;
            blurLevel = app.BlurLevelSlider.Value;
            
            % Generate data for disk phantom
            phantom = zeros(nx,ny);
            for ii = 1:size(app.circ, 1)
                cx = app.circ(ii,1); cy = app.circ(ii,2); rad = app.circ(ii,3); amp = app.circ(ii,4);
                t = find( ((xx-cx)/rad).^2 + ((yy-cy)/rad).^2 <= 1 );
                phantom(t) = amp * ones(size(t));
            end

            % Add noise
            noisyPhantom = phantom + noisyLevel * randn(size(phantom));

            % Apply blur
            blurredPhantom = imgaussfilt(noisyPhantom, blurLevel);

            % Display the edited phantom in UIAxes_2
            imagesc(x, y, blurredPhantom, 'Parent', app.UIAxes_2);
            colormap(app.UIAxes_2, 'gray');
            axis(app.UIAxes_2, 'square');
            app.UIAxes_2.DataAspectRatio = [1 1 1];

            app.editedImage = blurredPhantom;
        end

        % Button pushed function: OriginalSinogramButton
        function OriginalSinogramButtonPushed(app, event)
            nx = 170;
            dx = 2;	% 2 mm / pixel
            x = dx * ((1:nx)'-(nx+1)/2);

            theta = 0:1:179; % Projection angles
            app.sinogram = radon(app.originalImage, theta);

            % Display the sinogram in UIAxes_3
            imagesc(app.UIAxes_3, theta, x, app.sinogram);
            colormap(app.UIAxes_3, 'gray');
            xlabel(app.UIAxes_3, 'Projection Angle (degrees)');
            ylabel(app.UIAxes_3, 'Position');
        end

        % Button pushed function: SinogramButton
        function SinogramButtonPushed(app, event)
            nx = 170;
            dx = 2;	% 2 mm / pixel
            x = dx * ((1:nx)'-(nx+1)/2);

            theta = 0:1:179; % Projection angles
            app.sinogram = radon(app.editedImage, theta);

            % Display the sinogram in UIAxes_3
            imagesc(app.UIAxes_3, theta, x, app.sinogram);
            colormap(app.UIAxes_3, 'gray');
            xlabel(app.UIAxes_3, 'Projection Angle (degrees)');
            ylabel(app.UIAxes_3, 'Position');
        end

        % Selection changed function: ChooseFilterButtonGroup
        function ChooseFilterButtonGroupSelectionChanged(app, event)
        nx = 170;
        theta = 0:1:179; % Projection angles

            selectedButton = app.ChooseFilterButtonGroup.SelectedObject;
            if selectedButton== app.noneButton
                app.reconstructedImage = iradon(app.sinogram, theta, 'linear', 'none', 1, nx);
            elseif selectedButton == app.RamLakButton
                app.reconstructedImage = iradon(app.sinogram, theta, 'linear', 'Ram-Lak', 1, nx);
            elseif selectedButton == app.HammingButton
                app.reconstructedImage = iradon(app.sinogram, theta, 'linear', 'Hamming', 1, nx);
            elseif selectedButton == app.HanningButton
                app.reconstructedImage = iradon(app.sinogram, theta, 'linear', 'Hann', 1, nx);
            elseif selectedButton == app.CosineButton
                app.reconstructedImage = iradon(app.sinogram, theta, 'linear', 'Cosine', 1, nx);
            end
        end

        % Button pushed function: BackProjectionButton
        function BackProjectionButtonPushed(app, event)
    ori = double(app.originalImage);
    recons = double(flipud(app.reconstructedImage));

    % Display the reconstructed image in UIAxes_4
    imagesc(app.UIAxes_4, recons);
    colormap(app.UIAxes_4, 'gray');
    title(app.UIAxes_4, 'Reconstruction Image');
    axis(app.UIAxes_4, 'square');
    app.UIAxes_4.DataAspectRatio = [1 1 1];

    % Calculate PSNR
    MAX = 255;  % Maximum possible pixel value for double image
    MSE = mean((ori - recons).^2, 'all');
    PSNR = 10 * log10(MAX^2 / MSE);

    % Clip PSNR to the range [0, 1]
    PSNR = max(min(PSNR, 255), 0);
    ssimValue = ssim(recons, ori);

    % Update the PSNR and SSIM values in the app
    app.PSNREditField.Value = PSNR;
    app.SSIMEditField.Value = ssimValue;
        end

        % Button pushed function: ARTButton
        function ARTButtonPushed(app, event)
      % Prompt the user for the number of iterations
        prompt = 'Enter the number of ART iterations:';
        numIterations = str2double(inputdlg(prompt, 'ART Iterations', 1, {'200'}));

        if ~isnan(numIterations) && ~isempty(numIterations)
            % Call the ARTReconstruction function with the specified number of iterations
            app.ARTReconstruction(numIterations);
        else
            % Display an error message if the input is invalid
            errordlg('Invalid input. Please enter a valid number of iterations.', 'Error', 'modal');
        end

        ori = double(app.originalImage);
        recons = double(flipud(app.reconstructedImageART));

        % Calculate PSNR
        MAX = 255;  % Maximum possible pixel value for double image
        MSE = mean((ori - recons).^2, 'all');
        PSNR = 10 * log10(MAX^2 / MSE);
    
        % Clip PSNR to the range [0, 1]
        PSNR = max(min(PSNR, 255), 0);
        ssimValue = ssim(recons, ori);
    
        % Update the PSNR and SSIM values in the app
        app.PSNREditField.Value = PSNR;
        app.SSIMEditField.Value = ssimValue;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.sinogram_panel = uipanel(app.UIFigure);
            app.UIAxes_3 = uiaxes(app.sinogram_panel);
            title(app.UIAxes_3, 'Sinogram')
            app.phantom_panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.phantom_panel);
            title(app.UIAxes, 'Phantom')
            app.control_panel = uipanel(app.UIFigure);
            app.PhantomImageReconstructionLabel = uilabel(app.control_panel);
            app.PhantomImageReconstructionLabel.Text = 'Phantom Image Reconstruction';
            app.ChoosePhantomButtonGroup = uibuttongroup(app.control_panel);
            app.ChoosePhantomButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChoosePhantomButtonGroupSelectionChanged, true);
            app.ChoosePhantomButtonGroup.Title = 'Choose Phantom';
            app.Phantom1Button = uiradiobutton(app.ChoosePhantomButtonGroup);
            app.Phantom1Button.Text = 'Phantom 1';
            app.Phantom1Button.Value = true;
            app.Phantom2Button = uiradiobutton(app.ChoosePhantomButtonGroup);
            app.Phantom2Button.Text = 'Phantom 2';
            app.Phantom3Button = uiradiobutton(app.ChoosePhantomButtonGroup);
            app.Phantom3Button.Text = 'Phantom 3';
            app.dotButton = uiradiobutton(app.ChoosePhantomButtonGroup);
            app.dotButton.Visible = 'off';
            app.dotButton.Text = '';
            app.Phantom4Button = uiradiobutton(app.ChoosePhantomButtonGroup);
            app.Phantom4Button.Text = 'Phantom 4';
            app.ShowPhantomButton = uibutton(app.control_panel, 'push');
            app.ShowPhantomButton.ButtonPushedFcn = createCallbackFcn(app, @ShowPhantomButtonPushed, true);
            app.ShowPhantomButton.Text = 'Show Phantom';
            app.NoisyLevelSliderLabel = uilabel(app.control_panel);
            app.NoisyLevelSliderLabel.Text = 'Noisy Level';
            app.NoisyLevelSlider = uislider(app.control_panel);
            app.NoisyLevelSlider.Limits = [0 5];
            app.ShowEditedPhantomButton = uibutton(app.control_panel, 'push');
            app.ShowEditedPhantomButton.ButtonPushedFcn = createCallbackFcn(app, @ShowEditedPhantomButtonPushed, true);
            app.ShowEditedPhantomButton.Text = 'Show Edited Phantom';
            app.BlurLevelSliderLabel = uilabel(app.control_panel);
            app.BlurLevelSliderLabel.Text = 'Blur Level';
            app.BlurLevelSlider = uislider(app.control_panel);
            app.BlurLevelSlider.Limits = [0 5];
            app.SinogramButton = uibutton(app.control_panel, 'push');
            app.SinogramButton.ButtonPushedFcn = createCallbackFcn(app, @SinogramButtonPushed, true);
            app.SinogramButton.Text = 'Sinogram';
            app.OriginalSinogramButton = uibutton(app.control_panel, 'push');
            app.OriginalSinogramButton.ButtonPushedFcn = createCallbackFcn(app, @OriginalSinogramButtonPushed, true);
            app.OriginalSinogramButton.Text = 'Original Sinogram';
            app.ChooseFilterButtonGroup = uibuttongroup(app.control_panel);
            app.ChooseFilterButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChooseFilterButtonGroupSelectionChanged, true);
            app.ChooseFilterButtonGroup.Title = 'Choose Filter';
            app.noneButton = uiradiobutton(app.ChooseFilterButtonGroup);
            app.noneButton.Text = 'none';
            app.noneButton.Value = true;
            app.RamLakButton = uiradiobutton(app.ChooseFilterButtonGroup);
            app.RamLakButton.Text = 'Ram-Lak';
            app.HammingButton = uiradiobutton(app.ChooseFilterButtonGroup);
            app.HammingButton.Text = 'Hamming';
            app.dot2Button = uiradiobutton(app.ChooseFilterButtonGroup);
            app.dot2Button.Visible = 'off';
            app.dot2Button.Text = '';
            app.HanningButton = uiradiobutton(app.ChooseFilterButtonGroup);
            app.HanningButton.Text = 'Hanning';
            app.CosineButton = uiradiobutton(app.ChooseFilterButtonGroup);
            app.CosineButton.Text = 'Cosine';
            app.BackProjectionButton = uibutton(app.control_panel, 'push');
            app.BackProjectionButton.ButtonPushedFcn = createCallbackFcn(app, @BackProjectionButtonPushed, true);
            app.BackProjectionButton.Text = 'Back Projection';
            app.ProjekAkhirTeknologiPencitraanMedisKelompok1Label = uilabel(app.control_panel);
            app.ProjekAkhirTeknologiPencitraanMedisKelompok1Label.Text = 'Projek Akhir Teknologi Pencitraan Medis - Kelompok 1';
            app.PSNREditFieldLabel = uilabel(app.control_panel);
            app.PSNREditFieldLabel.Text = 'PSNR';
            app.PSNREditField = uieditfield(app.control_panel, 'numeric');
            app.SSIMEditFieldLabel = uilabel(app.control_panel);
            app.SSIMEditFieldLabel.Text = 'SSIM';
            app.SSIMEditField = uieditfield(app.control_panel, 'numeric');
            app.VirginiaValensieneEMariscaAuliaKizzaNaufalGiselaBeaAthaliaLabel = uilabel(app.control_panel);
            app.VirginiaValensieneEMariscaAuliaKizzaNaufalGiselaBeaAthaliaLabel.Text = 'Virginia Valensiene E, Marisca Aulia, Kizza Naufal, Gisela Bea Athalia Adyu';
            app.ARTButton = uibutton(app.control_panel, 'push');
            app.ARTButton.ButtonPushedFcn = createCallbackFcn(app, @ARTButtonPushed, true);
            app.ARTButton.Text = 'ART';
            app.edited_phantom_panel = uipanel(app.UIFigure);
            app.UIAxes_2 = uiaxes(app.edited_phantom_panel);
            title(app.UIAxes_2, 'Edited Phantom')
            app.reconstruction_panel = uipanel(app.UIFigure);
            app.UIAxes_4 = uiaxes(app.reconstruction_panel);
            title(app.UIAxes_4, 'Reconstruction Image')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Kelompok_1_Image_Reconstruction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end